classdef HexapawnApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BoardSizeSpinner       matlab.ui.control.Spinner
        BoardSizeSpinnerLabel  matlab.ui.control.Label
        StateLabel             matlab.ui.control.Label
        WarningText            matlab.ui.control.Label
        MoveText               matlab.ui.control.Label
        BeginButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private)
        green       % Green pawn locations
        red         % Red pawn locations
        moveShift   % 0 if green starts 1 if red does
        State       % Board state string
        move        % Move counter
        boardSize   % Size of the board (single value => square board)
        greenMoves  % Move order flag

    end

    methods (Access = private)
        
        function State = defaultStartState(app)
            NG = app.boardSize;
            NR = NG;
            State = blanks(NG*2+NR*2+3); % Each pawn two characters+ 2 separators+ 1 move flag
            GreenPawns = cell(1,NG);
            for iPawn = 1:NG
                GreenPawns{iPawn} = [char(iPawn+'A'-1), '1'];
            end
            GreenPawns = sort(GreenPawns);
            State(1:2*NG+1) = [GreenPawns{1:end},'-'];

            RedPawns = cell(1,NR);
            for iPawn = 1:NR
                RedPawns{iPawn} = [char(iPawn+'A'-1), num2str(app.boardSize)];
            end
            RedPawns = sort(RedPawns);
            State(2*NG+2:2*NG+2+2*NR) = [RedPawns{1:end},'-'];
            State(end) = 'G';
        end

        function drawBoard(app)
            app.State = getBoardState(app.green,app.red,app.greenMoves);
            app.StateLabel.Text = app.State;
            rightEnd = app.boardSize*2;
            topEnd = app.boardSize*2;
            set(app.UIFigure,'Resize','off')
            hold(app.UIAxes,'off');
            plot(app.UIAxes,1,1)
            hold(app.UIAxes,'on');
            for x = 2:2:rightEnd
                plot(app.UIAxes,[x x],[0 topEnd],'k','LineWidth',2);
            end
            for y = 0:2:topEnd
                plot(app.UIAxes,[0 rightEnd],[y y],'k','LineWidth',2);
            end
            for i=1:size(app.green)
                pos= [(2*app.green(i,1)-1.75)  (2*app.green(i,2)-1.75)   1.5   1.5];
                rectangle(app.UIAxes,'Position',pos,'FaceColor',[0 .5 0],'Curvature',[1 1]);
            end
            for i=1:size(app.red)
                pos= [(2*app.red(i,1)-1.75)  (2*app.red(i,2)-1.75)   1.5   1.5];
                rectangle(app.UIAxes,'Position',pos,'FaceColor',[.75 0 0],'Curvature',[1 1]);
            end
            LabelX=[{'A'},{'B'},{'C'},{'D'},{'E'},{'F'},{'G'},{'H'},{'I'},{'J'},{'K'},...
                {'L'},{'M'},{'N'},{'O'},{'P'},{'Q'},{'R'},{'S'},{'T'},{'U'},{'V'},{'W'}...
                ,{'X'},{'Y'},{'Z'}];
            LabelY=cell(1,26);
            for i = 1:26
                LabelY(i) = {[num2str(i)]};
            end
            set(app.UIAxes, 'XTick', 1:2:2*app.boardSize-1, 'XTickLabel', LabelX(1:app.boardSize),...
                'YTick',1:2:2*app.boardSize-1,'YTickLabel',LabelY(1:app.boardSize));
            title(app.UIAxes,'Hexapawn','Interpreter','Latex','FontSize',20)
        end
        
        function Moves = getAvailableMoves(app)
            Moves = [];             % each row [startFile, startRank, finishFile, finishRank]
            NG = size(app.green,1);
            NR = size(app.red,1);
            if app.greenMoves
                if NG == 0
                    return
                end
                for i=1:NG
                    start = app.green(i,:);
                    finish = start;
                    finish(2) = finish(2)+1;
                    for j =-1:1
                        finish(1)=start(1)+j;
                        [~,~,isValid]=validateMove(app.green,app.red, start, finish, app.greenMoves);
                        if isValid
                            Moves = [Moves; start, finish];
                        end
                    end
                end
            else
                if NR == 0
                    return
                end
                for i=1:NR
                    start = app.red(i,:);
                    finish = start;
                    finish(2) = finish(2)-1;
                    for j =-1:1
                        finish(1)=start(1)+j;
                        [~,~,isValid]=validateMove(app.green,app.red, start, finish, app.greenMoves);
                        if isValid
                            Moves = [Moves; start, finish];
                        end
                    end
                end
            end
            
        end

        function [start, finish] = getMove(app)
            start=ginput(1);
            start(1)= floor(start(1)/2) + 1;
            start(2)= floor(start(2)/2) + 1;
            app.highlight(start);
            %     start

            finish=ginput(1);
            finish(1)= floor(finish(1)/2) + 1;
            finish(2)= floor(finish(2)/2) + 1;
            %     finish
        end
        function highlight(app,square)
            x = square(1);
            y = square(2);
            plot(app.UIAxes, [(x-1)*2,x*2], [(y-1)*2,(y-1)*2], 'color',[0.7,0.7,0.2],'LineWidth',2)
            plot(app.UIAxes, [(x-1)*2,x*2], [y*2,y*2], 'color',[0.7,0.7,0.2],'LineWidth',2)
            plot(app.UIAxes, [(x-1)*2,(x-1)*2], [(y-1)*2,y*2], 'color',[0.7,0.7,0.2],'LineWidth',2)
            plot(app.UIAxes, [x*2,x*2], [(y-1)*2,y*2], 'color',[0.7,0.7,0.2],'LineWidth',2)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Check Matlab version, throw error if prior to r2020b
            assert(~verLessThan('Matlab', '9.9'), 'ginput not supported prior to Matlab r2020b.')

            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current
            app.boardSize = app.BoardSizeSpinner.Value;
            [app.green, app.red, app.moveShift] = BoardPrep();
            app.drawBoard();
            app.MoveText.Text = 'Click begin to start.';
            app.WarningText.Text = ' ';
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            app.boardSize = app.BoardSizeSpinner.Value;
            app.State = app.defaultStartState();
            [app.green, app.red, app.moveShift] = BoardPrep(app.State);
            
            app.drawBoard();
            app.BeginButton.Text = 'Game running.';
            app.BeginButton.FontColor = [0.5, 0.5, 0.5];
            app.move = 1;
            for i=1:100
                app.greenMoves = mod(app.move+app.moveShift,2);
                Moves = app.getAvailableMoves();
                if max(app.green(:,2)) == app.boardSize
                    app.WarningText.Text=' ';
                    app.MoveText.Text = 'Green wins!';
                    break
                end
                if min(app.red(:,2))==1
                    app.WarningText.Text=' ';
                    app.MoveText.Text = 'Red wins!';
                    break
                end
                if size(Moves,2) == 0
                    app.WarningText.Text=' ';
                    app.MoveText.Text = 'It is a draw!';
                    break
                end
                    
                
                if app.greenMoves
                    app.MoveText.Text = 'Green to move.';
                else
                    app.MoveText.Text = 'Red to move.';
                end
                
                [start, finish] = app.getMove();
                if finish(1)<= app.boardSize && finish(2)<=app.boardSize && finish(1)>=1 && finish(2)>=1
                    [app.green, app.red, isValid] = validateMove(app.green,app.red,start,finish,app.greenMoves);
                    if isValid
                        app.move = app.move+1;
                        app.WarningText.Text = ' ';
                    else
                        app.WarningText.Text = 'Invalid move';
                    end
                else
                    app.WarningText.Text = 'Invalid move';
                end

                app.drawBoard()

            end
            app.BeginButton.Text = 'Begin';
            app.BeginButton.FontColor = [0, 0, 0];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.BeginButton = uibutton(app.UIFigure, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            app.MoveText = uilabel(app.UIFigure);
            app.WarningText = uilabel(app.UIFigure);
            app.WarningText.Text = 'Label2';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'Label3';
            app.BoardSizeSpinnerLabel = uilabel(app.UIFigure);
            app.BoardSizeSpinnerLabel.Text = 'BoardSize';
            app.BoardSizeSpinner = uispinner(app.UIFigure);
            app.BoardSizeSpinner.Limits = [3 10];
            app.BoardSizeSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HexapawnApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end